classdef CrosstalkGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SaveCloseButton  matlab.ui.control.Button
        SliderLabel      matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img1
        img2
        
        trace1
        trace2
        
        scan
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scan, img1, img2)
            app.img1 = double(img1);
            app.img2 = double(img2);
            
            upper = ceil(max(app.img1(:))/max(app.img2(:)));
            app.Slider.Limits = [0,upper];
            
            app.trace1 = mean(img1,2);
            app.trace2 = mean(img2,2);
            
            app.scan = scan;
            
            k = scan.load_var('crosstalk_factor',0);
            app.Slider.Value = k;
            
            imagesc(app.UIAxes, app.img1 - k * app.img2);
            
            hold(app.UIAxes2,'on')
            cla(app.UIAxes2)
            plot(app.UIAxes2,app.trace1,'r-');
            plot(app.UIAxes2,app.trace2 * k,'g-');
            
            plot(app.UIAxes3,app.trace1 - app.trace2*k);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            k = event.Value;
            imagesc(app.UIAxes, app.img1 - k * app.img2);
            
            hold(app.UIAxes2,'on')
            cla(app.UIAxes2)
            plot(app.UIAxes2,app.trace1,'r-');
            plot(app.UIAxes2,app.trace2 * k,'g-');
            
            plot(app.UIAxes3,app.trace1 - app.trace2*k);
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            crosstalk_factor = app.Slider.Value;
            if isempty(crosstalk_factor) || isnan(crosstalk_factor)
                app.scan.clear_var('crosstalk_factor');
            else
                app.scan.save_var(crosstalk_factor);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveCloseButton = uibutton(app.UIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save & Close';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrosstalkGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end