classdef IgnoreEpisodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        scan
        out_varname
        
        ignored_episodes
        diameter_tbl
        vessel_index
        
        mouse_down = false
        rectangles
        selected_episode = 0
        selected_episode_side
    end
    
    methods (Access = public)
        
        function setup_vessel(app, vessel_index)
            cla(app.UIAxes);
            
            app.vessel_index = vessel_index;
            
            plot(app.UIAxes,app.diameter_tbl.diameter{vessel_index});
            
            app.plot_rectangles();
        end
        
        function plot_rectangles(app)
            y_min = min(app.diameter_tbl.diameter{app.vessel_index});
            y_max = max(app.diameter_tbl.diameter{app.vessel_index});
            
            % Make the number of rectangles match the number of episodes.
            if length(app.rectangles) > height(app.ignored_episodes)
                delete(app.rectangles(height(app.ignored_episodes)+1:end))
            end
            
            % Remove invalid rectangles from the list.
            if ~isempty(app.rectangles)
                app.rectangles(~isvalid(app.rectangles)) = [];
            end
            
            % Make a rectangle for each episode.
            for i = 1:height(app.ignored_episodes)
                x1 = app.ignored_episodes.start(i);
                x2 = app.ignored_episodes.end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                
                if isempty(app.rectangles)
                    app.rectangles = rectangle(app.UIAxes,"Position",pos);
                elseif length(app.rectangles) < i
                    app.rectangles(end+1) = rectangle(app.UIAxes,"Position",pos);
                else
                    app.rectangles(i).Position = pos;
                end
                
                % Hide rectangles of the other vessels.
                if app.vessel_index == app.ignored_episodes.vessel_index(i)
                    app.rectangles(i).Visible = true;
                else
                    app.rectangles(i).Visible = false;
                end
                
                if app.selected_episode == i
                    app.rectangles(i).EdgeColor = "red";
                else
                    app.rectangles(i).EdgeColor = "black";
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scan, in_varname, out_varname)
            app.scan = scan;
            app.out_varname = out_varname;
            
            app.diameter_tbl = scan.load_var(in_varname);
            app.UITable.Data = app.diameter_tbl;
            app.UITable.ColumnName = app.diameter_tbl.Properties.VariableNames;
            
            app.ignored_episodes = scan.load_var(out_varname,table());
            
            app.setup_vessel(1);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if event.Key == "a"
                x = app.UIAxes.CurrentPoint(1,1);
            
                y_min = min(app.diameter_tbl.diameter{app.vessel_index});
                y_max = max(app.diameter_tbl.diameter{app.vessel_index});
                
                width = diff(app.UIAxes.XLim) * 0.05;
                
                ignored_episode = table;
                ignored_episode.vessel_index = app.vessel_index;
                ignored_episode.start = x - width/2;
                ignored_episode.end = x + width/2;
                
                app.ignored_episodes = cat(1, app.ignored_episodes, ignored_episode);
                
                app.plot_rectangles();
            elseif event.Key == "d"
                if app.selected_episode ~= 0
                    app.ignored_episodes(app.selected_episode,:) = [];
                    app.plot_rectangles();
                end
            elseif event.Key == "s"
                if isempty(app.ignored_episodes)
                    begonia.logging.log(1,"Ignored episodes cleared")
                    app.scan.clear_var(app.out_varname);
                else
                    begonia.logging.log(1,"Saving ignored episodes")
                    ignored_episodes = app.ignored_episodes;
                    [~,I] = sort(ignored_episodes.start);
                    ignored_episodes = ignored_episodes(I,:)
                    app.scan.save_var(app.out_varname, ignored_episodes);
                end
            end
            
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            % Move the selected rectangle to the cursor position.
            if app.mouse_down && app.selected_episode ~= 0
                x = app.UIAxes.CurrentPoint(1,1);
                if app.selected_episode_side == "left" && ...
                        x < app.ignored_episodes.end(app.selected_episode)
                    
                    app.ignored_episodes.start(app.selected_episode) = x;
                    app.plot_rectangles();
                elseif app.selected_episode_side == "right" && ...
                        x > app.ignored_episodes.start(app.selected_episode)
                    
                    app.ignored_episodes.end(app.selected_episode) = x;
                    app.plot_rectangles();
                end
                
            end
            
            
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse_down = true;
            
            % Select the episode closest to the cursor.
            if ~isempty(app.ignored_episodes)
                ignored_episodes = app.ignored_episodes;
                
                % Invalidate episodes with the wrong vessel index.
                I = ignored_episodes.vessel_index ~= app.vessel_index;
                ignored_episodes.start(I) = nan;
                ignored_episodes.end(I) = nan;
                
                x = app.UIAxes.CurrentPoint(1,1);
                rec_edges = [ignored_episodes.start; ignored_episodes.end];
                
                % Find the rectangle edge closest to the cursor.
                rec_I = 1:height(app.ignored_episodes);
                rec_I = [rec_I,rec_I];
                i = begonia.util.val2idx(rec_edges,x);
                rec_i = rec_I(i);
                
                if i > length(app.rectangles)
                    app.selected_episode_side = "right";
                else
                    app.selected_episode_side = "left";
                end
                app.selected_episode = rec_i;
            else
                app.selected_episode = 0;
            end
            
            app.plot_rectangles();
            
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouse_down = false;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.setup_vessel(indices(1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IgnoreEpisodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end