classdef MarkEpisodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        SaveButton  matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        scan
        diameter_red
        diameter_green
        episode_choices
        
        rectangles
        static_rectangles
        
        trace_green
        trace_red
        
        mouse_down = false
        selected_episode = ""
        selected_episode_side
        
        episodes
        
        static_episodes
        static_episodes_colors
    end
    
    methods
        
        function add_episode(app,ep,ep_start,ep_end)
            ep = string(ep);
            
            ep_id = string(begonia.util.make_uuid());
            
            new_ep = table(ep,ep_start,ep_end,ep_id);
            if isempty(app.episodes)
                app.episodes = new_ep;
            else
                app.episodes = cat(1,app.episodes,new_ep);
            end
            
            app.plot_rectangles();
        end
        
        function load_plot(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            hold(app.UIAxes,'on');
            hold(app.UIAxes_2,'on');
            
            green = app.diameter_green.diameter{1};
            red = app.diameter_red.diameter{1};
            N = length(green);
            t = (0:N-1) / app.diameter_green.vessel_fs(1);
            
            app.trace_green = plot(app.UIAxes, t, green);
            app.trace_red = plot(app.UIAxes_2, t, red);
            
            linkaxes([app.UIAxes,app.UIAxes_2],'x')
            xlim(app.UIAxes,[t(1),t(end)])
            
            % Plot the static episodes
            y_min = min(green);
            y_max = max(green);
            for i = 1:height(app.static_episodes)
                x1 = app.static_episodes.state_start(i);
                x2 = app.static_episodes.state_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = app.static_episodes.color(i,:);
                
                app.static_rectangles = rectangle(app.UIAxes, ...
                    "Position",pos, ...
                    "FaceColor",[color,0.2], ...
                    "EdgeColor",[0,0,0,0]);
            end
            
            % Legend of static episodes
            for i = 1:height(app.static_episodes_colors)
                h1(i) = plot(app.UIAxes, NaN, 'square', ...
                    'MarkerSize', 10, ...
                    'MarkerEdgeColor',app.static_episodes_colors.color(i,:), ...
                    'MarkerFaceColor',app.static_episodes_colors.color(i,:), ...
                    'DisplayName', char(app.static_episodes_colors.state(i)));
            end
            legend(app.UIAxes, h1);
            
            % Legend of episodes
            for i = 1:height(app.episode_choices)
                h2(i) = plot(app.UIAxes_2, NaN, 'square', ...
                    'MarkerSize', 10, ...
                    'MarkerEdgeColor',app.episode_choices.color(i,:), ...
                    'MarkerFaceColor',app.episode_choices.color(i,:), ...
                    'DisplayName', char(app.episode_choices.ep(i)));
            end
            legend(app.UIAxes_2, h2);
            
            % Move green to the front.
            if ~isempty(app.UIAxes.Children)
                arr = app.UIAxes.Children;
                i = ismember(arr,app.trace_green);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes.Children = [app.trace_green;arr];
            end
            
            app.plot_rectangles();
        end
        
        
        function save(app)
            if isempty(app.episodes)
                begonia.logging.log(1,"Clearing episodes")
                app.scan.clear_var('clean_episodes');
            else
                begonia.logging.log(1,"Saving episodes")
                clean_episodes = app.episodes;
                [~,I] = sort(clean_episodes.ep_start);
                clean_episodes = clean_episodes(I,:)
                app.scan.save_var(clean_episodes);
            end
        end
        
        function plot_rectangles(app)
            
            red = app.diameter_red.diameter{1};
            
            y_min = min(red);
            y_max = max(red);
            
            % Make the number of rectangles match the number of episodes.
            if length(app.rectangles) > height(app.episodes)
                delete(app.rectangles(height(app.episodes)+1:end))
            end
            
            % Remove invalid rectangles from the list.
            if ~isempty(app.rectangles)
                app.rectangles(~isvalid(app.rectangles)) = [];
            end
            
            % Add colors to the episodes.
            if isempty(app.episodes)
                episodes = table;
            else
                episodes = innerjoin(app.episodes,app.episode_choices);
            end
            
            % Make a rectangle for each episode.
            for i = 1:height(episodes)
                x1 = episodes.ep_start(i);
                x2 = episodes.ep_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = episodes.color(i,:);
                
                % Plot rectangles.
                if isempty(app.rectangles)
                    app.rectangles = rectangle(app.UIAxes_2, ...
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                elseif length(app.rectangles) < i
                    app.rectangles(end+1) = rectangle(app.UIAxes_2, ... 
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                else
                    app.rectangles(i).Position = pos;
                    app.rectangles(i).FaceColor = [color,0.6];
                    app.rectangles(i).LineWidth = 2;
                end
                
                % Hightlight the selected episode.
                if app.selected_episode == episodes.ep_id(i)
                    app.rectangles(i).EdgeColor = "red";
                else
                    app.rectangles(i).EdgeColor = "black";
                end
            end
            
            % Move red to the front.
            if ~isempty(app.UIAxes_2.Children)
                arr = app.UIAxes_2.Children;
                i = ismember(arr,app.trace_red);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes_2.Children = [app.trace_red;arr];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scan)
            %% Init variables
            ep = ["Clean NREM","Clean IS","Clean REM","Clean Vessel Baseline","Clean Locomotion","Clean Whisking","Clean Quiet"]';
            color = zeros(0,3);
            color(end+1,:) = [117,208,250];
            color(end+1,:) = [177,124,246]; 
            color(end+1,:) = [82,28,138]; 
            color(end+1,:) = [100,256,100];
            color(end+1,:) = [230,0,0];
            color(end+1,:) = [230,230,0];
            color(end+1,:) = [39,143,144];
            color = color / 256;
            episode_choices = table(ep,color);
            
            
            % Define the static episode colors.
            state = ["NREM","IS","REM","Vessel Baseline","Locomotion","Whisking","Quiet"]';
            color = zeros(0,3);
            color(end+1,:) = [117,208,250];
            color(end+1,:) = [177,124,246]; 
            color(end+1,:) = [82,28,138]; 
            color(end+1,:) = [100,256,100];
            color(end+1,:) = [230,0,0];
            color(end+1,:) = [230,230,0];
            color(end+1,:) = [39,143,144];
            color = color / 256;
            static_episodes_colors = table(state,color);
            
            diameter_red = scan.load_var('diameter_red');
            diameter_green = scan.load_var('diameter_green');
            
            static_episodes = scan.load_var('episodes',[]);
            
            % Add colors to the static episodes
            if ~isempty(static_episodes)
                static_episodes = innerjoin(static_episodes,static_episodes_colors);
            end
            
            %% Assign instance variables.
            app.scan = scan;
            app.diameter_green = diameter_green;
            app.diameter_red = diameter_red;
            app.episode_choices = episode_choices;
            app.episodes = scan.load_var('clean_episodes',table);
            app.static_episodes = static_episodes;
            app.static_episodes_colors = static_episodes_colors;
            
            % Assign gui component variables.
            app.DropDown.Items = episode_choices.ep;
            app.DropDown.Value = episode_choices.ep(1);
            
            app.load_plot();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse_down = true;
            
            if isempty(app.trace_green)
                return;
            end
            
            % Select the episode close to the cursor.
            if ~isempty(app.episodes)
                
                x = app.UIAxes_2.CurrentPoint(1,1);
                ep_edges = [app.episodes.ep_start; app.episodes.ep_end];
                
                % Decide which episode to select.
                i = begonia.util.val2idx(ep_edges,x);
                dist = abs(x - ep_edges(i));
                
                % The episode edge must be closer than 2.5% to be selected.
                threshold = 0.025 * diff(app.UIAxes_2.XLim);
                
                if dist < threshold
                    ep_I = 1:height(app.episodes);
                    ep_I = [ep_I,ep_I];
                    ep_I = ep_I(i);
                    
                    if i > length(app.rectangles)
                        app.selected_episode_side = "right";
                    else
                        app.selected_episode_side = "left";
                    end
                    app.selected_episode = app.episodes.ep_id(ep_I);
                else
                    app.selected_episode = "";
                end
            else
                app.selected_episode = "";
            end
            
            app.plot_rectangles();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouse_down = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            % Move the selected rectangle to the cursor position.
            if app.mouse_down && app.selected_episode ~= ""
                x = app.UIAxes_2.CurrentPoint(1,1);
                i = app.selected_episode == app.episodes.ep_id;
                if app.selected_episode_side == "left" && ...
                        x < app.episodes.ep_end(i)
                    
                    app.episodes.ep_start(i) = x;
                    app.plot_rectangles();
                elseif app.selected_episode_side == "right" && ...
                        x > app.episodes.ep_start(i)
                    
                    app.episodes.ep_end(i) = x;
                    app.plot_rectangles();
                end
                
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if event.Key == "a"
                x = app.UIAxes_2.CurrentPoint(1,1);
                
                width = diff(app.UIAxes.XLim) * 0.05;
                app.add_episode(app.DropDown.Value,x-width/2,x+width/2);
            elseif event.Key == "d"
                if app.selected_episode ~= ""
                    I = app.episodes.ep_id == app.selected_episode;
                    app.episodes(I,:) = [];
                    app.plot_rectangles();
                end
            elseif event.Key == "s"
                app.save();
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.save();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Endfoot tube')
            ylabel(app.UIAxes, 'Diameter (um)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Lumen')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Diameter (um)')
            zlabel(app.UIAxes_2, 'Z')
            app.DropDown = uidropdown(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkEpisodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end