classdef MarkDilations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        scan
        diameter_red
        diameter_green
        episode_choices
        
        lines
        
        static_rectangles
        
        trace_green
        trace_red
        
        mouse_down = false
        selected_timepoint = ""
        
        timepoints
        
        static_episodes
        static_episodes_colors
    end
    
    methods
        
        function add_timepoint(app,t0)
            id = string(begonia.util.make_uuid());
            
            new_timepoint = table(t0, id);
            if isempty(app.timepoints)
                app.timepoints = new_timepoint;
            else
                app.timepoints = cat(1,app.timepoints,new_timepoint);
            end
            
            app.plot_timepoints();
        end
        
        function load_plot(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            hold(app.UIAxes,'on');
            hold(app.UIAxes_2,'on');
            
            green = app.diameter_green.diameter{1};
            red = app.diameter_red.diameter{1};
            N = length(green);
            t = (0:N-1) / app.diameter_green.vessel_fs(1);
            
            app.trace_green = plot(app.UIAxes, t, green);
            app.trace_red = plot(app.UIAxes_2, t, red);
            
            linkaxes([app.UIAxes,app.UIAxes_2],'x')
            xlim(app.UIAxes,[t(1),t(end)])
            
            % Plot the static episodes
            y_min = min(green);
            y_max = max(green);
            for i = 1:height(app.static_episodes)
                x1 = app.static_episodes.state_start(i);
                x2 = app.static_episodes.state_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = app.static_episodes.color(i,:);
                
                app.static_rectangles = rectangle(app.UIAxes, ...
                    "Position",pos, ...
                    "FaceColor",[color,0.2], ...
                    "EdgeColor",[0,0,0,0]);
            end
            
            % Legend of static episodes
            for i = 1:height(app.static_episodes_colors)
                h1(i) = plot(app.UIAxes, NaN, 'square', ...
                    'MarkerSize', 10, ...
                    'MarkerEdgeColor',app.static_episodes_colors.color(i,:), ...
                    'MarkerFaceColor',app.static_episodes_colors.color(i,:), ...
                    'DisplayName', char(app.static_episodes_colors.state(i)));
            end
            legend(h1,'AutoUpdate','off');
            
            % Legend of timepoints
            h2 = plot(app.UIAxes_2, NaN, '--', ...
                'LineWidth', 1, ...
                'Color',"black", ...
                'DisplayName', "Dilation timepoint");
            legend(h2,'AutoUpdate','off');
            
            % Move green trace in front of the static episodes.
            if ~isempty(app.UIAxes.Children)
                arr = app.UIAxes.Children;
                i = ismember(arr,app.trace_green);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes.Children = [app.trace_green;arr];
            end
            
            app.plot_timepoints();
        end
        
        
        function save(app)
            if isempty(app.timepoints)
                begonia.logging.log(1,"Clearing episodes")
                app.scan.clear_var('dilation_timepoints');
            else
                begonia.logging.log(1,"Saving episodes")
                dilation_timepoints = app.timepoints;
                [~,I] = sort(dilation_timepoints.t0);
                dilation_timepoints = dilation_timepoints(I,:)
                app.scan.save_var(dilation_timepoints);
            end
        end
        
        function plot_timepoints(app)
            
            red = app.diameter_red.diameter{1};
            
            y_min = min(red);
            y_max = max(red);
            
            % Make the number of lines match the number of timepoints.
            if length(app.lines) > height(app.timepoints)
                delete(app.lines(height(app.timepoints)+1:end))
            end
            
            % Remove invalid lines from the list.
            if ~isempty(app.lines)
                app.lines(~isvalid(app.lines)) = [];
            end
            
            % Make a line for each timepoint.
            timepoints = app.timepoints;
            for i = 1:height(timepoints)
                x = timepoints.t0(i);
                
                if isempty(app.lines)
                    app.lines = plot(app.UIAxes_2, [x,x], [y_min,y_max], ...
                        "Color", "black", ...
                        "LineWidth", 1, ...
                        "LineStyle","--");
                    app.move_red_trace_top();
                elseif length(app.lines) < i
                    app.lines(end+1) = plot(app.UIAxes_2, [x,x], [y_min,y_max], ...
                        "Color", "black", ...
                        "LineWidth", 1, ...
                        "LineStyle","--");
                    app.move_red_trace_top();
                else
                    app.lines(i).XData = [x,x];
                    app.lines(i).YData = [y_min,y_max];
                end
                
                % Hightlight the selected episode.
                if app.selected_timepoint == timepoints.id(i)
                    app.lines(i).Color = "red";
                else
                    app.lines(i).Color = "black";
                end
            end
            
        end
        
        function move_red_trace_top(app)
            % Move red trace in front of the time points / lines.
            if ~isempty(app.UIAxes_2.Children)
                arr = app.UIAxes_2.Children;
                i = ismember(arr,app.trace_red);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes_2.Children = [app.trace_red;arr];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scan)
            %% Init variables
            
            % Define the static episode colors.
            state = ["NREM","IS","REM","Vessel Baseline","Locomotion","Whisking","Quiet"]';
            color = zeros(0,3);
            color(end+1,:) = [117,208,250];
            color(end+1,:) = [177,124,246]; 
            color(end+1,:) = [82,28,138]; 
            color(end+1,:) = [100,256,100];
            color(end+1,:) = [230,0,0];
            color(end+1,:) = [230,230,0];
            color(end+1,:) = [39,143,144];
            color = color / 256;
            static_episodes_colors = table(state,color);
            
            diameter_red = scan.load_var('diameter_red');
            diameter_green = scan.load_var('diameter_green');
            
            static_episodes = scan.load_var('episodes',[]);
            
            % Add colors to the static episodes
            if ~isempty(static_episodes)
                static_episodes = innerjoin(static_episodes,static_episodes_colors);
            end
            
            %% Assign instance variables.
            app.scan = scan;
            app.diameter_green = diameter_green;
            app.diameter_red = diameter_red;
            app.timepoints = scan.load_var('dilation_timepoints',table);
            app.static_episodes = static_episodes;
            app.static_episodes_colors = static_episodes_colors;
            
            app.load_plot();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse_down = true;
            
            app.selected_timepoint = "";
            
            if isempty(app.trace_green)
                return;
            end
            
            % Select the episode close to the cursor.
            if ~isempty(app.timepoints)
                t0 = app.timepoints.t0;
                x = app.UIAxes_2.CurrentPoint(1,1);
                
                % Find the closest timepoint.
                i = begonia.util.val2idx(t0,x);
                dist = abs(x - t0(i));
                
                % The line must be closer than 2.5% of the view to be selected.
                threshold = 0.025 * diff(app.UIAxes_2.XLim);
                
                if dist < threshold
                    app.selected_timepoint = app.timepoints.id(i);
                end
            end
            
            app.plot_timepoints();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouse_down = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            % Move the selected line/timepoint to the cursor position.
            if app.mouse_down && app.selected_timepoint ~= ""
                x = app.UIAxes_2.CurrentPoint(1,1);
                i = app.selected_timepoint == app.timepoints.id;
                app.timepoints.t0(i) = x;
                app.plot_timepoints();
            end
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if event.Key == "a"
                x = app.UIAxes_2.CurrentPoint(1,1);
                app.add_timepoint(x);
            elseif event.Key == "d"
                if app.selected_timepoint ~= ""
                    I = app.timepoints.id == app.selected_timepoint;
                    app.timepoints(I,:) = [];
                    app.plot_timepoints();
                end
            elseif event.Key == "s"
                app.save();
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Endfoot tube')
            ylabel(app.UIAxes, 'Diameter (um)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Lumen')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Diameter (um)')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkDilations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end