classdef config_pupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ThresholdEditField       matlab.ui.control.EditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ThresholdSlider          matlab.ui.control.Slider
        TimeEditField            matlab.ui.control.EditField
        TimeEditFieldLabel       matlab.ui.control.Label
        FrameEditField           matlab.ui.control.EditField
        FrameEditFieldLabel      matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tr
        video_reader
        frame
        frametimes
        threshold

        img_handle
        mask_handle
    end
    
    methods (Access = public)
        
        function show_frame(app)
            if isempty(app.frame) || isempty(app.threshold)
                return;
            end
            
            img = app.video_reader.read(app.frame);

            % Impose threshold mask on image.
            mask = iris.util.threshold_pupil(img, app.threshold);
            mask = im2uint8(mask);
            img(:,:,3) = max(img(:,:,3),mask);
            
            % Draw image.
            if isempty(app.img_handle)
                app.img_handle = imshow(img, "Parent", app.UIAxes);
            else
                app.img_handle.CData = img;
            end

        end

        function set_frame(app, frame)
            app.frame = round(frame);
            app.frame = max(app.frame, 1);
            app.frame = min(app.frame, height(app.frametimes));

            % Update frame text.
            app.FrameEditField.Value = num2str(app.frame);
            
            % Update time text.
            time = seconds(app.frametimes.t(app.frame));
            time.Format = "hh:mm:ss";
            app.TimeEditField.Value = string(time);

            app.show_frame();
        end
        
        function set_threshold(app,threshold)
            app.ThresholdSlider.Value = threshold;

            app.threshold = round(threshold);
            app.threshold = max(app.threshold, 1);
            app.threshold = min(app.threshold, 255);

            app.ThresholdEditField.Value = sprintf("%d",app.threshold);
            
            app.show_frame();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tr)
            app.tr = tr;

            [app.video_reader,app.frametimes] = iris.util.read_pupil_video(tr);
            if isempty(app.video_reader)
                close(app.UIFigure);
                error("Missing mp4 file");
            end

            % Init slider value.
            app.Slider.Value = 1;
            app.Slider.Limits = [1,height(app.frametimes)];
            
            threshold = tr.load_var("pupil_threshold",45);
            app.set_threshold(threshold)
            app.set_frame(1);

        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            app.set_threshold(event.Value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            pupil_threshold = app.threshold;
            begonia.logging.log(1,"Threshold = %d",pupil_threshold);
            app.tr.save_var(pupil_threshold);
            close(app.UIFigure);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.set_frame(event.Value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.set_threshold(app.threshold - 1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.set_threshold(app.threshold + 1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Eye of Sauron')
            zlabel(app.UIAxes, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Enable = 'off';
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'text');
            app.FrameEditField.Editable = 'off';
            app.FrameEditField.Enable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Enable = 'off';
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.TimeEditField.Editable = 'off';
            app.TimeEditField.Enable = 'off';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.ThresholdEditField.Editable = 'off';
            app.ThresholdEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = config_pupil(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end