classdef config_pupil_multi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TimeEditField        matlab.ui.control.EditField
        TimeEditFieldLabel   matlab.ui.control.Label
        FrameEditField       matlab.ui.control.EditField
        FrameEditFieldLabel  matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tr
        video_reader
        frame
        frametimes
        
        threshold
        threshold_handles
        interpolation_handle
        frame_handle

        img_handle
        mask_handle
    end
    
    methods (Access = public)
        
        function show_frame(app)
            if isempty(app.frame) || isempty(app.threshold)
                return;
            end
            
            img = app.video_reader.read(app.frame);

            % Impose threshold mask on image.
            [x,y] = iris.util.interpolate_pupil_threshold(app.threshold,app.frametimes);
            theta = y(app.frame);
            mask = iris.util.threshold_pupil(img, theta);
            mask = im2uint8(mask);
            img(:,:,3) = max(img(:,:,3),mask);
            
            % Draw image.
            if isempty(app.img_handle)
                app.img_handle = imshow(img, "Parent", app.UIAxes);
            else
                app.img_handle.CData = img;
            end

        end

        function set_frame(app, frame)
            app.frame = round(frame);
            app.frame = max(app.frame, 1);
            app.frame = min(app.frame, height(app.frametimes));

            % Update frame text.
            app.FrameEditField.Value = num2str(app.frame);
            
            % Update time text.
            time = seconds(app.frametimes.t(app.frame));
            time.Format = "hh:mm:ss";
            app.TimeEditField.Value = string(time);
            
            % Move the frame handle on the threshold UI.
            if isempty(app.frame_handle)
                app.frame_handle = plot(app.UIAxes2,[app.frame,app.frame],[0,255],"-r","LineWidth",2);
            else
                app.frame_handle.XData = [app.frame,app.frame];
            end

            app.show_frame();
        end
        
        function add_threshold(app, theta, frame)
            if nargin < 3
                [x,y] = iris.util.interpolate_pupil_threshold(app.threshold,app.frametimes);
                theta = y(app.frame);
            end
            if nargin < 3
                frame = app.frame;
            end
            id = string(begonia.util.make_uuid());
            row = table(frame, theta, id);
            app.threshold = cat(1, app.threshold, row);
            app.update_threshold_plot();
        end
        
        function roi_moved(app,src,evt)
            % Update the threshold  in the table.
            frame = evt.CurrentPosition(1);
            theta = evt.CurrentPosition(2);
            id = string(src.Label);
            i = app.threshold.id == id;
            app.threshold.frame(i) = frame;
            app.threshold.theta(i) = theta;
            
            app.update_threshold_plot();
        end
        
        function roi_deleted(app,src,evt)
            % Update the threshold  in the table.
            id = string(src.Label);
            i = app.threshold.id == id;
            app.threshold(i,:) = [];
            
            % Create a new threshold if empty.
            if isempty(app.threshold)
                app.add_threshold();
            end
            app.update_threshold_plot();
        end
        
        function update_threshold_plot(app)
            if isempty(app.threshold_handles)
                app.threshold_handles = containers.Map();
            end
            
            % Make or update a plot handle for each threshold.
            for i = 1:height(app.threshold)
                % Check if threshold point handle exists.
                if ~app.threshold_handles.isKey(app.threshold.id(i))
                    % Make new handle.
                    hold(app.UIAxes2,"on");
                    h = images.roi.Point(app.UIAxes2,"Position",[app.threshold.frame(i),app.threshold.theta(i)]);
                    h.Label = app.threshold.id(i);
                    h.LabelVisible = "off";
                    addlistener(h,"MovingROI",@app.roi_moved);
                    addlistener(h,"DeletingROI",@app.roi_deleted);
                    app.threshold_handles(app.threshold.id(i)) = h;
                end
            end
            
            % Plot the interpolation line. 
            [x,y] = iris.util.interpolate_pupil_threshold(app.threshold,app.frametimes);
            if isempty(app.interpolation_handle)
                hold(app.UIAxes2,"on");
                app.interpolation_handle = plot(app.UIAxes2,x,y,"--k");
            else
                app.interpolation_handle.YData = y;
            end
            app.show_frame();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tr)
            app.tr = tr;

            [app.video_reader,app.frametimes] = iris.util.read_pupil_video(tr);
            if isempty(app.video_reader)
                close(app.UIFigure);
                error("Missing mp4 file");
            end

            % Init frame slider value.
            app.Slider.Value = 1;
            app.Slider.Limits = [1,height(app.frametimes)];
            
            % Init default value of threshold axis.
            app.UIAxes2.YLim = [1,100];
            app.UIAxes2.XLim = [1,height(app.frametimes)];
            
            threshold = tr.load_var("pupil_threshold",45);
            if isscalar(threshold)
                app.threshold = table;
                app.add_threshold(threshold,height(app.frametimes)/20);
            else
                app.threshold = threshold;
            end
            app.set_frame(1);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            pupil_threshold = app.threshold;
            begonia.logging.log(1,"Saving threshold");
            pupil_threshold
            app.tr.save_var(pupil_threshold);
            close(app.UIFigure);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if event.Key == "a"
                app.add_threshold();
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.set_frame(event.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Eye of Sauron')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Threshold')
            xlabel(app.UIAxes2, 'Frame')
            zlabel(app.UIAxes2, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Enable = 'off';
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'text');
            app.FrameEditField.Editable = 'off';
            app.FrameEditField.Enable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Enable = 'off';
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.TimeEditField.Editable = 'off';
            app.TimeEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = config_pupil_multi(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end