classdef mark_sleep_episodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StateDropDown       matlab.ui.control.DropDown
        StateDropDownLabel  matlab.ui.control.Label
        UIAxes6             matlab.ui.control.UIAxes
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tr % DataLocation / DataNode object.

        sleep_episodes % Episode data.

        trace % Time series data.

        top_trace % Handle to the ecog_filt plot.

        rectangles % Handles to the rectangles representing episodes.
        
        selected_episode = "" % The episode_id of the selected episodes.
        selected_episode_side % String, keep track of which side of the episode is selected.

        mouse_down = false % Boolean, keep track of mouse button down state.
    end
    
    methods (Access = private)
        function add_episode(app,ep,ep_start,ep_end)
            ep = string(ep);
            
            ep_id = string(begonia.util.make_uuid());

            ep_duration = ep_end - ep_start;
            
            % Make a single episode row.
            new_ep = table(ep,ep_start,ep_end,ep_duration,ep_id);
            
            % Add color.
            new_ep = innerjoin(new_ep,iris.get_state_color_table());

            if isempty(app.sleep_episodes)
                app.sleep_episodes = new_ep;
            else
                app.sleep_episodes = cat(1,app.sleep_episodes,new_ep);
            end
            
            app.plot_rectangles();
        end
        
        function plot_rectangles(app)
            y_min = min(app.trace.y{1});
            y_max = max(app.trace.y{1});
            
            % Make the number of rectangles match the number of episodes.
            if length(app.rectangles) > height(app.sleep_episodes)
                delete(app.rectangles(height(app.sleep_episodes)+1:end))
            end
            
            % Remove invalid rectangles from the list.
            if ~isempty(app.rectangles)
                app.rectangles(~isvalid(app.rectangles)) = [];
            end
            
            % Make a rectangle for each episode.
            for i = 1:height(app.sleep_episodes)
                x1 = app.sleep_episodes.ep_start(i);
                x2 = app.sleep_episodes.ep_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = app.sleep_episodes.color(i,:);
                
                if isempty(app.rectangles)
                    app.rectangles = rectangle(app.UIAxes, ...
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                elseif length(app.rectangles) < i
                    app.rectangles(end+1) = rectangle(app.UIAxes, ... 
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                else
                    app.rectangles(i).Position = pos;
                    app.rectangles(i).FaceColor = [color,0.6];
                    app.rectangles(i).LineWidth = 2;
                end
                
                if app.selected_episode == app.sleep_episodes.ep_id(i)
                    app.rectangles(i).EdgeColor = "red";
                else
                    app.rectangles(i).EdgeColor = "black";
                end
            end
            
            % Move trace to the front.
            if ~isempty(app.UIAxes.Children)
                arr = app.UIAxes.Children;
                i = ismember(arr,app.top_trace);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes.Children = [app.top_trace;arr];
            end
        end

        function save(app)
            if isempty(app.sleep_episodes)
                begonia.logging.log(1,"Clearing episodes")
                app.tr.clear_var("sleep_episodes");
            else
                begonia.logging.log(1,"Saving sleep episodes")
                sleep_episodes = app.sleep_episodes;

                % Sort.
                [~,I] = sort(sleep_episodes.ep_start);
                sleep_episodes = sleep_episodes(I,:);

                % Remove color data.
                sleep_episodes.color = [];

                disp(sleep_episodes)
                app.tr.save_var(sleep_episodes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tr)
            assert(tr.has_var("ecog_filt"), "Sleep metrics must be calculated.");
            
            % Assign data.
            app.tr = tr;
            app.trace = tr.load_var("delta");

            % Load sleep episodes or an empty table.
            app.sleep_episodes = tr.load_var("sleep_episodes",table);

            % Add color column.
            if ~isempty(app.sleep_episodes)
                app.sleep_episodes = innerjoin(app.sleep_episodes, iris.get_state_color_table());
            end

            app.top_trace = iris.util.app.plot_time_series(app.trace,app.UIAxes);

            app.plot_rectangles();

            % Plot remaining traces.
            iris.util.app.plot_time_series(tr.load_var("theta"),app.UIAxes2);

            iris.util.app.plot_time_series(tr.load_var("sigma"),app.UIAxes3);

            iris.util.app.plot_time_series(tr.load_var("theta_ratio"),app.UIAxes4);

            iris.util.app.plot_time_series(tr.load_var("ecog_filt"),app.UIAxes5);

            iris.util.app.plot_time_series(tr.load_var("emg"),app.UIAxes6);

            linkaxes([app.UIAxes,app.UIAxes2,app.UIAxes3,app.UIAxes4,app.UIAxes5,app.UIAxes6],'x');
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse_down = true;
            
            % Select the episode close to the cursor.
            if ~isempty(app.sleep_episodes)
                
                x = app.UIAxes.CurrentPoint(1,1);
                ep_edges = [app.sleep_episodes.ep_start; app.sleep_episodes.ep_end];
                
                % Decide which episode to select.
                i = begonia.util.val2idx(ep_edges,x);
                dist = abs(x - ep_edges(i));
                
                % The episode edge must be closer than 2.5% to be selected.
                threshold = 0.025 * diff(app.UIAxes.XLim);
                
                if dist < threshold
                    ep_I = 1:height(app.sleep_episodes);
                    ep_I = [ep_I,ep_I];
                    ep_I = ep_I(i);
                    
                    if i > length(app.rectangles)
                        app.selected_episode_side = "right";
                    else
                        app.selected_episode_side = "left";
                    end
                    app.selected_episode = app.sleep_episodes.ep_id(ep_I);
                else
                    app.selected_episode = "";
                end
            else
                app.selected_episode = "";
            end
            
            app.plot_rectangles();
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            % Move the selected rectangle to the cursor position.
            if app.mouse_down && app.selected_episode ~= ""
                x = app.UIAxes.CurrentPoint(1,1);
                i = app.selected_episode == app.sleep_episodes.ep_id;
                if app.selected_episode_side == "left" && ...
                        x < app.sleep_episodes.ep_end(i)
                    
                    app.sleep_episodes.ep_start(i) = x;
                    app.sleep_episodes.ep_duration(i) = ...
                        app.sleep_episodes.ep_end(i) - app.sleep_episodes.ep_start(i);
                    app.plot_rectangles();
                elseif app.selected_episode_side == "right" && ...
                        x > app.sleep_episodes.ep_start(i)
                    
                    app.sleep_episodes.ep_end(i) = x;
                    app.sleep_episodes.ep_duration(i) = ...
                        app.sleep_episodes.ep_end(i) - app.sleep_episodes.ep_start(i);
                    app.plot_rectangles();
                end
                
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouse_down = false;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if event.Key == "a"
                x = app.UIAxes.CurrentPoint(1,1);
                
                width = diff(app.UIAxes.XLim) * 0.05;
                app.add_episode(app.StateDropDown.Value,x-width/2,x+width/2);
            elseif event.Key == "d"
                if app.selected_episode ~= ""
                    I = app.sleep_episodes.ep_id == app.selected_episode;
                    app.sleep_episodes(I,:) = [];
                    app.plot_rectangles();
                end
            elseif event.Key == "s"
                app.save();
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Items = {'NREM', 'IS', 'REM', 'Awakening'};
            app.StateDropDown.Value = 'NREM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mark_sleep_episodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end