classdef ConfigurationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveconfigButton               matlab.ui.control.Button
        autosavesonexitLabel           matlab.ui.control.Label
        IgnoremaskPanel                matlab.ui.container.Panel
        but_clear_ignore_mask          matlab.ui.control.Button
        but_add_secondary_component    matlab.ui.control.Button
        TresholdingandprocessingPanel  matlab.ui.container.Panel
        check_apply_adaptive           matlab.ui.control.CheckBox
        check_apply_imadjust           matlab.ui.control.CheckBox
        MinLabel                       matlab.ui.control.Label
        min_slider                     matlab.ui.control.Slider
        MaxLabel                       matlab.ui.control.Label
        max_slider                     matlab.ui.control.Slider
        MostreliableaxisDropDownLabel  matlab.ui.control.Label
        most_reliable_axis_pick        matlab.ui.control.DropDown
        PreviewPanel                   matlab.ui.container.Panel
        FrameLabel                     matlab.ui.control.Label
        frame_slider                   matlab.ui.control.Slider
        ax_preview                     matlab.ui.control.UIAxes
        ax_component                   matlab.ui.control.UIAxes
        ax_output                      matlab.ui.control.UIAxes
        ax_ignore                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recording % Description
        trial
        current_frame_idx = 1
        ignore_mask = []
    
        tresh_min = 100;
        tresh_max = 255;
        
        frame % Description
        frame_bin % Description
        eye_roi % Description
        eye_measure_roi
        
        eye_area = [200, 200, 200, 200];
        eye_measure = [100 100; 200 200];
        eye_measure_pix
        
        diameter_px = 0
    end
  
    
    methods 
        
        function set_recording(app, trial)
            app.trial = trial;
            
            h = waitbar(0.1, "Loading data");
            rec_path = fullfile(trial.path, "pupil_data");
            app.recording = yucca.mod.puptool.PupilRecording(rec_path);
            app.frame_slider.Limits = [1 length(app.recording .frame_times)];
            
            waitbar(0.9, h, "Loading settings");
            if ~app.load()
                app.set_frame(150);
            end
            
            app.update_preview();
            delete(h);
        end
        
        function set_frame(app, frame)
            app.current_frame_idx = frame;
            app.update_preview();
            app.update_bin_image();
        end
       
        
        function save(app)
            config.min = app.tresh_min;
            config.max = app.tresh_max;
            config.use_imadjust = app.check_apply_imadjust.Value;
            config.preview_frame_idx = app.current_frame_idx;
            config.eye_rect = app.eye_area;
            config.eye_measure = app.eye_measure;
            config.reliable_axis = lower(app.most_reliable_axis_pick.Value);
            config.ignore_mask = app.ignore_mask;
            
            % calculate the eye measure:
            pos = app.eye_measure; 
            x1 = pos(1,1);
            y1 = pos(1,2);
            x2 = pos(2,1);
            y2 = pos(2,2);
            config.eye_measure_px = sqrt((x1-x2)^2+(y1-y2)^2);
            
            app.trial.save_var('puptrack_config', config);
            app.trial.save_var('puptrack_ignore_mask', app.ignore_mask );
        end
        
        function had = load(app)
            if app.trial.has_var('puptrack_config')
                config = app.trial.load_var('puptrack_config');
                app.eye_area = config.eye_rect;
                
                
                app.tresh_min = config.min;
                app.tresh_max = config.max;
                
                app.check_apply_imadjust.Value = config.use_imadjust;
                
                % reliable axis:
                if isfield(config, 'reliable_axis')
                    ax = config.reliable_axis;
                    switch ax
                        case 'vertical'
                            app.most_reliable_axis_pick.Value = 'Vertical';
                        case 'horizontal'
                            app.most_reliable_axis_pick.Value = 'Horizontal';

                    end
                end
                
                % eye measure, with support for older version 
                if isfield(config, 'eye_measure')
                    app.eye_measure = config.eye_measure;
                end
                
                if app.trial.has_var('puptrack_ignore_mask')
                    app.ignore_mask = app.trial.load_var('puptrack_ignore_mask');
                else
                    app.reinit_ignoremask()
                end
                
                app.set_frame(config.preview_frame_idx);


                had = true;
            else
                had = false; 
            end
        end
        
        
        function update_preview(app)
            app.frame = app.recording.read(app.current_frame_idx);
            imshow(app.frame, 'parent', app.ax_preview);
            
            % re-create roi selection roi:
            app.eye_roi = images.roi.Rectangle(gca,'Position', app.eye_area, 'Label', 'Eye area', 'Parent', app.ax_preview);
            addlistener(app.eye_roi,'MovingROI', @(~,~) app.handle_eyeroi_move() );
            
            app.eye_measure_roi = images.roi.Line(gca,'Position', app.eye_measure, 'Parent', app.ax_preview, 'color', 'r', 'Label', 'Eye width');
            addlistener(app.eye_measure_roi,'MovingROI', @(~,~) app.handle_eyemeasure_move() );
            
            % adjust limits:
            xlim(app.ax_preview, [0 size(app.frame, 2)]);
            ylim(app.ax_preview, [0 size(app.frame, 1)]);
        end
        
        
        function update_bin_image(app)
            [app.frame_bin, bbox, app.frame, app.diameter_px] = yucca.mod.puptool.analyse_frame(...
                app.frame, app.eye_area, app.tresh_min, app.tresh_max, app.check_apply_imadjust.Value, app.check_apply_adaptive.Value, app.ignore_mask, ...
                lower(app.most_reliable_axis_pick.Value));
            
            imagesc(app.ax_component, app.frame_bin);
            caxis(app.ax_component, [0 2]);
            
            imshow(app.frame ,'parent', app.ax_output);
            if ~isempty(bbox)
                dia_str = char("Pupil " + app.diameter_px + 0 + " px");
                images.roi.Rectangle(app.ax_output,'position', bbox, 'Label', dia_str, 'parent', app.ax_output);
            end
            
            % show ignore mask update:
            imagesc(app.ax_ignore, app.ignore_mask);
            caxis(app.ax_ignore, [0 1]);

            xlim(app.ax_component, [0 size(app.frame_bin, 2)]);
            ylim(app.ax_component, [0 size(app.frame_bin, 1)]);
            
            drawnow();
        end
        

        function handle_eyeroi_move(app)
            app.eye_area = app.eye_roi.Position;
            app.reinit_ignoremask()
            app.update_bin_image();
        end
        
        function handle_eyemeasure_move(app)
            pos = app.eye_measure_roi.Position; 
            x1 = pos(1,1);
            y1 = pos(1,2);
            x2 = pos(2,1);
            y2 = pos(2,2);
            
            app.eye_measure = pos;
            app.eye_measure_pix = sqrt((x1-x2)^2+(y1-y2)^2);
        end
        
        function reinit_ignoremask(app)
            app.ignore_mask = [];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_start(app, trial)
            app.set_recording(trial);
        end

        % Value changing function: min_slider
        function on_min_slider_changing(app, event)
            app.tresh_min = event.Value;
            app.update_bin_image();
        end

        % Value changing function: max_slider
        function on_max_slider_changing(app, event)
            app.tresh_max = event.Value;
            app.update_bin_image();
        end

        % Value changing function: frame_slider
        function on_frame_changing(app, event)
            app.set_frame(round(event.Value));
            drawnow();
        end

        % Value changed function: check_apply_imadjust
        function on_imadjust_chcked(app, event)
            app.update_bin_image();
        end

        % Value changed function: check_apply_adaptive
        function on_adaptive_checked(app, event)
            app.update_bin_image();
        end

        % Button pushed function: SaveconfigButton
        function on_push_save(app, event)
            app.save();
        end

        % Close request function: UIFigure
        function on_close_req(app, event)
            app.save();
            delete(app)
            
        end

        % Callback function
        function handler_add_to_ignore(app, event)
            % negframe = app.frame_bin == 2;
            % app.ignore_mask = app.ignore_mask & negframe;
            % app.update_bin_image();
        end

        % Button pushed function: but_add_secondary_component
        function handle_add_secondary(app, event)
            negframe = app.frame_bin == 1;
            
            if isempty(app.ignore_mask)
                app.ignore_mask = negframe;
            end
            
            % filthy workaround for ridiculus rounding errors:
            if ~isequal(size(negframe), size(app.ignore_mask))
                app.ignore_mask = imresize(app.ignore_mask, size(negframe));
            end
            
            app.ignore_mask = app.ignore_mask | negframe;
            app.update_bin_image();
        end

        % Button pushed function: but_clear_ignore_mask
        function handle_clear(app, event)
            app.reinit_ignoremask();
            app.update_bin_image();
        end

        % Value changed function: most_reliable_axis_pick
        function handle_reliable_axis_changed(app, event)

            app.update_bin_image();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @on_close_req, true);
            app.SaveconfigButton = uibutton(app.UIFigure, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @on_push_save, true);
            app.SaveconfigButton.Text = 'Save config';
            app.autosavesonexitLabel = uilabel(app.UIFigure);
            app.autosavesonexitLabel.Text = '(auto-saves on exit)';
            app.IgnoremaskPanel = uipanel(app.UIFigure);
            app.IgnoremaskPanel.Title = 'Ignore mask';
            app.but_clear_ignore_mask = uibutton(app.IgnoremaskPanel, 'push');
            app.but_clear_ignore_mask.ButtonPushedFcn = createCallbackFcn(app, @handle_clear, true);
            app.but_clear_ignore_mask.Text = 'Clear';
            app.but_add_secondary_component = uibutton(app.IgnoremaskPanel, 'push');
            app.but_add_secondary_component.ButtonPushedFcn = createCallbackFcn(app, @handle_add_secondary, true);
            app.but_add_secondary_component.Text = 'Add secondary component';
            app.TresholdingandprocessingPanel = uipanel(app.UIFigure);
            app.TresholdingandprocessingPanel.Title = 'Tresholding and processing';
            app.check_apply_adaptive = uicheckbox(app.TresholdingandprocessingPanel);
            app.check_apply_adaptive.ValueChangedFcn = createCallbackFcn(app, @on_adaptive_checked, true);
            app.check_apply_adaptive.Enable = 'off';
            app.check_apply_adaptive.Text = 'Apply adaptive treshold';
            app.check_apply_imadjust = uicheckbox(app.TresholdingandprocessingPanel);
            app.check_apply_imadjust.ValueChangedFcn = createCallbackFcn(app, @on_imadjust_chcked, true);
            app.check_apply_imadjust.Text = 'Apply imadjust';
            app.MinLabel = uilabel(app.TresholdingandprocessingPanel);
            app.MinLabel.Text = 'Min';
            app.min_slider = uislider(app.TresholdingandprocessingPanel);
            app.min_slider.Limits = [0 256];
            app.min_slider.MajorTicks = [0 256];
            app.min_slider.ValueChangingFcn = createCallbackFcn(app, @on_min_slider_changing, true);
            app.MaxLabel = uilabel(app.TresholdingandprocessingPanel);
            app.MaxLabel.Text = 'Max';
            app.max_slider = uislider(app.TresholdingandprocessingPanel);
            app.max_slider.Limits = [0 256];
            app.max_slider.MajorTicks = [0 255];
            app.max_slider.MajorTickLabels = {'0', '256'};
            app.max_slider.ValueChangingFcn = createCallbackFcn(app, @on_max_slider_changing, true);
            app.max_slider.Value = 256;
            app.MostreliableaxisDropDownLabel = uilabel(app.TresholdingandprocessingPanel);
            app.MostreliableaxisDropDownLabel.Text = 'Most reliable axis';
            app.most_reliable_axis_pick = uidropdown(app.TresholdingandprocessingPanel);
            app.most_reliable_axis_pick.Items = {'Vertical', 'Horizontal'};
            app.most_reliable_axis_pick.ValueChangedFcn = createCallbackFcn(app, @handle_reliable_axis_changed, true);
            app.most_reliable_axis_pick.Value = 'Vertical';
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.FrameLabel = uilabel(app.PreviewPanel);
            app.FrameLabel.Text = 'Frame:';
            app.frame_slider = uislider(app.PreviewPanel);
            app.frame_slider.ValueChangingFcn = createCallbackFcn(app, @on_frame_changing, true);
            app.ax_preview = uiaxes(app.UIFigure);
            title(app.ax_preview, 'Frame')
            app.ax_preview.XTick = [];
            app.ax_preview.YTick = [];
            app.ax_component = uiaxes(app.UIFigure);
            title(app.ax_component, 'Pupil component')
            app.ax_component.XTick = [];
            app.ax_component.YTick = [];
            app.ax_output = uiaxes(app.UIFigure);
            title(app.ax_output, 'Output view')
            app.ax_output.XTick = [];
            app.ax_output.YTick = [];
            app.ax_ignore = uiaxes(app.UIFigure);
            title(app.ax_ignore, 'Ignore area')
            app.ax_ignore.XTick = [];
            app.ax_ignore.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigurationTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)on_start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end