classdef Rotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RootFigure           matlab.ui.Figure
        RotationSliderLabel  matlab.ui.control.Label
        RotationSlider       matlab.ui.control.Slider
        SaverotationButton   matlab.ui.control.Button
        StopframeButton      matlab.ui.control.Button
        StartframeButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        manager
        application
        data_change_listener
        view_data_change_listener
        view
    end
    
    methods (Access = private)
        
        function handle_view_changed(app,sr,ev)
            if ~isvalid(app)
                return;
            end
            
            % clear old event listeners:
            if ~isempty(app.data_change_listener)
                delete(app.data_change_listener);
            end
            if ~isempty(app.view_data_change_listener)
                delete(app.view_data_change_listener);
            end
    
            app.manager = ev.viewmanager;

            % the new items could be empty - in whcih case we simply hide
            % outselves:
            if isempty(ev.viewmanager) || isempty(ev.to)
                app.RootFigure.Visible = false;
                app.view = [];
                return
            else
                app.RootFigure.Visible = true;
                
                % this steams focus, so we must focus top view again:
                ev.viewmanager.focus_top_view();
            
                app.manager.data.assign_changeflag("rotation","rotation_gui");
                
                app.data_change_listener = addlistener(app.manager.data, ...
                   'on_data_changed', @(~, ~) app.handle_data_changed());
            
                app.view = ev.to;
                
                app.view_data_change_listener = addlistener(ev.to.data, ...
                   'on_data_changed', @(~, ~) app.handle_data_changed());
                
                app.handle_data_changed();
            end
            
        end
        
        function handle_data_changed(app,sr,ev)
            
            if ~isvalid(app) || isempty(app.manager) || ~isvalid(app.manager) || isempty(app.view)
                return;
            end

            [~, ~, m_read,m_flagged] = app.manager.data.shorts();
            
            if ~m_flagged("rotation_gui")
                return;
            end
            
            rotation = m_read("rotation");
            app.RotationSlider.Value = rotation;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, application)
            addlistener(application, 'on_view_changed',...
                @app.handle_view_changed);
            
            app.application = application;
        end

        % Value changing function: RotationSlider
        function RotationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.manager.data.write("rotation",changingValue);
        end

        % Button pushed function: SaverotationButton
        function SaverotationButtonPushed(app, event)
            tseries = app.manager.data.read("tseries");
            rotation = app.manager.data.read("rotation");
            tseries.save_var("rotation",rotation);
            begonia.logging.log(1,"Saving rotation");
        end

        % Button pushed function: StopframeButton
        function StopframeButtonPushed(app, event)
            tseries = app.manager.data.read("tseries");
            cf = app.manager.current_frame;
            tseries.save_var("Stopframe",cf);
            begonia.logging.log(1,"Saving stopframe");
        end

        % Button pushed function: StartframeButton
        function StartframeButtonPushed(app, event)
            tseries = app.manager.data.read("tseries");
            cf = app.manager.current_frame;
            tseries.save_var("Startframe",cf);
            begonia.logging.log(1,"Saving startframe");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RootFigure = uifigure('Visible', 'off');
            app.RootFigure.Name = 'Preprocessing Parameters';
            app.RotationSliderLabel = uilabel(app.RootFigure);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.RootFigure);
            app.RotationSlider.Limits = [-180 180];
            app.RotationSlider.ValueChangingFcn = createCallbackFcn(app, @RotationSliderValueChanging, true);
            app.SaverotationButton = uibutton(app.RootFigure, 'push');
            app.SaverotationButton.ButtonPushedFcn = createCallbackFcn(app, @SaverotationButtonPushed, true);
            app.SaverotationButton.Text = 'Save rotation';
            app.StopframeButton = uibutton(app.RootFigure, 'push');
            app.StopframeButton.ButtonPushedFcn = createCallbackFcn(app, @StopframeButtonPushed, true);
            app.StopframeButton.Text = 'Stop frame';
            app.StartframeButton = uibutton(app.RootFigure, 'push');
            app.StartframeButton.ButtonPushedFcn = createCallbackFcn(app, @StartframeButtonPushed, true);
            app.StartframeButton.Text = 'Start frame';
            % Show the figure after all components are created
            app.RootFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rotation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RootFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RootFigure)
        end
    end
end