classdef CameraMarker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SaveButton           matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        Switch               matlab.ui.control.Switch
        RegionDropDown       matlab.ui.control.DropDown
        RegionDropDownLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties 
        videoreader
        dloc
        circle
        radius
        img
        camera_rois
        row
        mask_handles
        brush_indices_x
        brush_indices_y
        pressed = false
        ready = false
    end
    
    methods (Access = private)
        function show_at_time(app,t)
            app.videoreader.CurrentTime = t;
            im = app.videoreader.readFrame();
            if isempty(app.img)
                app.img = imshow(im,'Parent',app.UIAxes);
            else
                app.img.CData = im;
            end
            app.UIAxes.XLim = [0,app.videoreader.Width];
            app.UIAxes.YLim = [0,app.videoreader.Height];
        end
        
        function plot_circle(app)
            x = app.UIAxes.CurrentPoint(1,1);
            y = app.UIAxes.CurrentPoint(1,2);
            
            x = max(x,0);
            y = max(y,0);
            
            x = min(x,app.videoreader.Width);
            y = min(y,app.videoreader.Height);
            
            th = 0:pi/50:2*pi;
            
            xunit = app.radius * cos(th) + x;
            yunit = app.radius * sin(th) + y;
            
            if isempty(app.circle)
                app.circle = plot(app.UIAxes, xunit, yunit, "LineWidth", 2);
            else
                app.circle.XData = xunit;
                app.circle.YData = yunit;
            end

            app.draw();
        end
        
        function draw(app)
            if ~app.pressed
                return;
            end
            
            x = app.UIAxes.CurrentPoint(1,1);
            y = app.UIAxes.CurrentPoint(1,2);
            x = round(x);
            y = round(y);
            
            mask = app.camera_rois.mask{app.row};
            
            if x > size(mask,2) || x < 0 || ...
                    y > size(mask,1) || y < 0
                return;
            end
            
            X = x + app.brush_indices_x;
            Y = y + app.brush_indices_y;
            
            X(X < 1) = 1;
            X(X > size(mask,2)) = size(mask,2);
            
            Y(Y < 1) = 1;
            Y(Y > size(mask,1)) = size(mask,1);
            
            I = sub2ind(size(mask),Y,X);
            
            if app.Switch.Value == "Draw"
                mask(I) = true;
            else
                mask(I) = false;
            end
            
            app.camera_rois.mask{app.row} = mask;
            
            app.plot_mask();
        end
        
        function plot_mask(app, row)
            if nargin < 2
                row = app.row;
            end
            mask = app.camera_rois.mask{row};
            color = app.camera_rois.color(row,:);
            rgb = repmat(mask,1,1,3) .* reshape(color,1,1,3);
            if isempty(app.mask_handles{row})
                app.mask_handles{row} = imshow(rgb, "Parent", app.UIAxes);
            
                % Put the circle plot on the top
                objs = app.UIAxes.Children;
                objs(objs == app.circle) = [];
                objs = [app.circle; objs];
                app.UIAxes.Children = objs;
            else
                app.mask_handles{row}.CData = rgb;
            end
            app.mask_handles{row}.AlphaData = mask * 0.7;
        end
        
        function set_radius(app, r)
            if r < 0
                r = 0;
            end
            
            app.radius = r;
            r2 = r*r;
            
            mat = false(2*r+1, 2*r+1);
            
            for i = -r:r
                for j = -r:r
                    if r2 >= i*i + j*j
                        ii = i + r + 1;
                        jj = j + r + 1;
                        mat(ii,jj) = true;
                    end
                end
            end
            
            [x,y] = find(mat);
            
            app.brush_indices_x = x - r;
            app.brush_indices_y = y - r;
            
            app.plot_circle();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, videoreader, dloc)
            N = length(app.RegionDropDown.Items);
            
            app.videoreader = videoreader;
            app.dloc = dloc;
            app.row = find(ismember(app.RegionDropDown.Items, app.RegionDropDown.Value));
            app.mask_handles = cell(N,1);
            app.Slider.Limits = [0,floor(app.videoreader.Duration)];
            
            camera_rois = dloc.load_var("camera_rois", []);
            if isempty(camera_rois)
                region = app.RegionDropDown.Items';
                mask = cell(N,1);
                color = prism(N);
                for i = 1:N
                    mask{i} = false(videoreader.Height,videoreader.Width);
                end
                
                camera_rois = table(region,mask,color);
            else
                begonia.logging.log(1,"Loading existing markings");
            end
            app.camera_rois = camera_rois;
            
            hold(app.UIAxes,"on")
            
            app.show_at_time(0);
            app.set_radius(25);
            
            for i = 1:N
                app.plot_mask(i);
            end
            
            app.ready = true;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if app.ready
                app.show_at_time(event.Value);
            end
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            app.row = find(ismember(app.RegionDropDown.Items, app.RegionDropDown.Value));
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.pressed = true;
            if app.ready
                app.plot_circle();
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.pressed = false;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            if app.ready
                app.set_radius(app.radius + event.VerticalScrollCount);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.ready
                app.plot_circle();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            camera_rois = app.camera_rois;
            nothing_marked = true;
            for i = 1:height(camera_rois)
                if any(camera_rois.mask{i}(:))
                    nothing_marked = false;
                    break
                end
            end
            if nothing_marked
                begonia.logging.log(1,"Nothing marked");
                app.dloc.clear_var('camera_rois');
            else
                begonia.logging.log(1,"Saving ROIs")
                app.dloc.save_var(camera_rois);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
             switch event.Key
                 case "a"
                     items = app.RegionDropDown.Items;
                     value = app.RegionDropDown.Value;
                     i = find(ismember(items, value));
                     N = length(items);
                     
                     if i == N
                         app.RegionDropDown.Value = items{1};
                     else
                         app.RegionDropDown.Value = items{i+1};
                     end
                     app.RegionDropDownValueChanged([]);
                 case "s"
                     if app.Switch.Value == "Draw"
                         app.Switch.Value = "Erase";
                     else
                         app.Switch.Value = "Draw";
                     end
                 case "d"
                     app.SaveButtonPushed([]);
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RegionDropDownLabel = uilabel(app.UIFigure);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.UIFigure);
            app.RegionDropDown.Items = {'Wheel', 'Whisker', 'Breath'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = 'Wheel';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Draw', 'Erase'};
            app.Switch.Value = 'Draw';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraMarker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end