classdef MarkEpisodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        SaveButton  matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
        UITable     matlab.ui.control.Table
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dloc
        varname
        id
        x
        y
        episode_choices
        
        row
        
        rectangles
        trace
        
        mouse_down = false
        selected_episode = ""
        selected_episode_side
        
        episodes
        
        static_episodes
        static_episodes_colors
    end
    
    methods
        
        function add_episode(app,ep,ep_start,ep_end)
            ep = string(ep);
            
            ep_id = string(begonia.util.make_uuid());
            
            new_ep = table(ep,ep_start,ep_end,ep_id);
            new_ep = [app.id(app.row,:),new_ep];
            if isempty(app.episodes)
                app.episodes = new_ep;
            else
                app.episodes = cat(1,app.episodes,new_ep);
            end
            
            app.plot_rectangles();
        end
        
        function select_trace(app,row)
            app.row = row;
            
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            
            app.trace = plot(app.UIAxes, app.x{row}, app.y{row});
            
            
            % Plot the static episodes
            y_min = min(app.y{app.row});
            y_max = max(app.y{app.row});
            
            for i = 1:height(app.static_episodes)
                x1 = app.static_episodes.ep_start(i);
                x2 = app.static_episodes.ep_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = app.static_episodes.color(i,:);
                
                rectangle(app.UIAxes, ...
                    "Position",pos, ...
                    "FaceColor",[color,0.2], ...
                    "EdgeColor",[0,0,0,0]);
            end
            
            % Legend of episodes
            for i = 1:height(app.episode_choices)
                h(i) = plot(app.UIAxes, NaN, 'square', ...
                    'MarkerSize', 10, ...
                    'MarkerEdgeColor',app.episode_choices.color(i,:), ...
                    'MarkerFaceColor',app.episode_choices.color(i,:), ...
                    'DisplayName', char(app.episode_choices.ep(i)));
            end
            for i = 1:height(app.static_episodes_colors)
                h(end+1) = plot(app.UIAxes, NaN, 'square', ...
                    'MarkerSize', 10, ...
                    'MarkerEdgeColor',app.static_episodes_colors.color(i,:), ...
                    'MarkerFaceColor',app.static_episodes_colors.color(i,:), ...
                    'DisplayName', char(app.static_episodes_colors.ep(i)));
            end
            legend(h);
            
            app.plot_rectangles();
        end
        
        function save(app)
            if isempty(app.episodes)
                begonia.logging.log(1,"Clearing episodes")
                app.dloc.clear_var(app.varname);
            else
                begonia.logging.log(1,"Saving episodes")
                episodes = app.episodes;
                [~,I] = sort(episodes.ep_start);
                episodes = episodes(I,:);
                episodes(:,:)
                app.dloc.save_var(app.varname, episodes);
            end
        end
        
        function plot_rectangles(app)
            y_min = min(app.y{app.row});
            y_max = max(app.y{app.row});
            
            % Find episodes matching the current trace.
            if isempty(app.episodes)
                episodes = table;
            else
                episodes = innerjoin(app.episodes,app.id(app.row,:));
                % Add color information
                episodes = innerjoin(episodes,app.episode_choices);
            end
            
            % Make the number of rectangles match the number of episodes.
            if length(app.rectangles) > height(episodes)
                delete(app.rectangles(height(episodes)+1:end))
            end
            
            % Remove invalid rectangles from the list.
            if ~isempty(app.rectangles)
                app.rectangles(~isvalid(app.rectangles)) = [];
            end
            
            % Make a rectangle for each episode.
            for i = 1:height(episodes)
                x1 = episodes.ep_start(i);
                x2 = episodes.ep_end(i);
                pos = [x1, y_min, x2 - x1, y_max - y_min];
                color = episodes.color(i,:);
                
                if isempty(app.rectangles)
                    app.rectangles = rectangle(app.UIAxes, ...
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                elseif length(app.rectangles) < i
                    app.rectangles(end+1) = rectangle(app.UIAxes, ... 
                        "Position",pos, ...
                        "FaceColor",[color,0.6], ...
                        "LineWidth",2);
                else
                    app.rectangles(i).Position = pos;
                    app.rectangles(i).FaceColor = [color,0.6];
                    app.rectangles(i).LineWidth = 2;
                end
                
                if app.selected_episode == episodes.ep_id(i)
                    app.rectangles(i).EdgeColor = "red";
                else
                    app.rectangles(i).EdgeColor = "black";
                end
            end
            
            % Move trace to the front.
            if ~isempty(app.UIAxes.Children)
                arr = app.UIAxes.Children;
                i = ismember(arr,app.trace);
                if ~isempty(i)
                    arr(i) = [];
                end
                app.UIAxes.Children = [app.trace;arr];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dloc, varname, id, x, y, episode_choices, static_episodes)
            if nargin < 8
                static_episodes = [];
            end
            
            app.dloc = dloc;
            app.varname = varname;
            app.id = id;
            app.x = x;
            app.y = y;
            app.episode_choices = episode_choices;
            app.episodes = dloc.load_var(varname,table);
            app.static_episodes = static_episodes;
            
            % Find colors to the static episodes
            if isempty(static_episodes)
                app.static_episodes_colors = table;
            else
                ep = unique(app.static_episodes.ep);
                color = begonia.util.distinguishable_colors(length(ep));
                app.static_episodes_colors = table(ep,color);
                
                app.static_episodes = innerjoin(app.static_episodes,app.static_episodes_colors);
            end
            
            app.DropDown.Items = episode_choices.ep;
            app.DropDown.Value = episode_choices.ep(1);
            
            app.UITable.ColumnName = id.Properties.VariableNames;
            app.UITable.Data = id;
            
            app.select_trace(1);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1,1);
            
            app.select_trace(row)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse_down = true;
            
            if isempty(app.row)
                return;
            end
            
            % Find episodes matching the current trace.
            if isempty(app.episodes)
                episodes = table;
            else
                episodes = innerjoin(app.episodes,app.id(app.row,:));
            end
            
            % Select the episode close to the cursor.
            if ~isempty(episodes)
                
                x = app.UIAxes.CurrentPoint(1,1);
                ep_edges = [episodes.ep_start; episodes.ep_end];
                
                % Decide which episode to select.
                i = begonia.util.val2idx(ep_edges,x);
                dist = abs(x - ep_edges(i));
                
                % The episode edge must be closer than 2.5% to be selected.
                threshold = 0.025 * diff(app.UIAxes.XLim);
                
                if dist < threshold
                    ep_I = 1:height(episodes);
                    ep_I = [ep_I,ep_I];
                    ep_I = ep_I(i);
                    
                    if i > length(app.rectangles)
                        app.selected_episode_side = "right";
                    else
                        app.selected_episode_side = "left";
                    end
                    app.selected_episode = episodes.ep_id(ep_I);
                else
                    app.selected_episode = "";
                end
            else
                app.selected_episode = "";
            end
            
            app.plot_rectangles();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouse_down = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            % Move the selected rectangle to the cursor position.
            if app.mouse_down && app.selected_episode ~= ""
                x = app.UIAxes.CurrentPoint(1,1);
                i = app.selected_episode == app.episodes.ep_id;
                if app.selected_episode_side == "left" && ...
                        x < app.episodes.ep_end(i)
                    
                    app.episodes.ep_start(i) = x;
                    app.plot_rectangles();
                elseif app.selected_episode_side == "right" && ...
                        x > app.episodes.ep_start(i)
                    
                    app.episodes.ep_end(i) = x;
                    app.plot_rectangles();
                end
                
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if event.Key == "a"
                x = app.UIAxes.CurrentPoint(1,1);
                
                width = diff(app.UIAxes.XLim) * 0.05;
                app.add_episode(app.DropDown.Value,x-width/2,x+width/2);
            elseif event.Key == "d"
                if app.selected_episode ~= ""
                    I = app.episodes.ep_id == app.selected_episode;
                    app.episodes(I,:) = [];
                    app.plot_rectangles();
                end
            elseif event.Key == "s"
                app.save();
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.save();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkEpisodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end